classdef AbbasCentral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PSTHButton                matlab.ui.control.Button
        AlignmentDropDown         matlab.ui.control.DropDown
        AlignmentDropDownLabel    matlab.ui.control.Label
        DigraphPanel              matlab.ui.container.Panel
        PlotsPanel                matlab.ui.container.Panel
        OffsetSlider              matlab.ui.control.Slider
        OffsetsecondsSliderLabel  matlab.ui.control.Label
        NeuronDropDown            matlab.ui.control.DropDown
        NeuronDropDownLabel       matlab.ui.control.Label
        RasterButton              matlab.ui.control.Button
        SessionDropDown           matlab.ui.control.DropDown
        SessionDropDownLabel      matlab.ui.control.Label
        SubjectDropDown           matlab.ui.control.DropDown
        SubjectDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        sessions % all sessions in the current dataset
        metadata
        currentSession % index of currently selected subject sessions
        neuronNo
        alignment
        offset
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.sessions = evalin('base', 'vipSessions');
            app.metadata = evalin('base', 'vipMetadata');
            s = app.metadata.subjects;
            s = [{'Select a subject'}, s];
            app.SubjectDropDown.Items = s;
            app.offset = 2;

        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)

            value = app.SubjectDropDown.Value;
            if ~strcmp(string(value), "Select a subject")
                subSessions = arrayfun(@(x) strcmp(x.info.name, value), app.sessions);
                [x{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
                sessionNames = x{2}(subSessions);
                sessionNames = [{'Select a session'}, sessionNames];
                app.SessionDropDown.Items = sessionNames;
            end
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            if ~strcmp(string(value), "Select a session")
                [X{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
                whichSession = arrayfun(@(x) strcmp(x, value), X{2});
                app.currentSession = app.sessions(whichSession);
                neuronString = arrayfun(@num2str, 1:numel(app.currentSession.spikes), 'uni', 0);
                neuronString = [{'Select a neuron'}, neuronString];
                app.NeuronDropDown.Items = neuronString;
                delete(app.DigraphPanel.Children)
                app.currentSession.plot_digraph([], app.DigraphPanel);
                alignments = fields(app.currentSession.timestamps.keys)';
                alignments = cellfun(@(x) x(3:end), alignments, 'uni', 0);
                alignments = [{'Select an alignment'}, alignments];
                app.AlignmentDropDown.Items = alignments;
            end
        end

        % Value changed function: NeuronDropDown
        function NeuronDropDownValueChanged(app, event)
            value = app.NeuronDropDown.Value;
            if ~strcmp(string(value), "Select a neuron")
                app.neuronNo = str2double(app.NeuronDropDown.Value);
            end
        end

        % Button pushed function: RasterButton
        function RasterButtonPushed(app, event)

            if app.neuronNo <= numel(app.currentSession.spikes) && ismember(strcat('x_', app.alignment), fields(app.currentSession.timestamps.keys))
                delete(app.PlotsPanel.Children)
                app.currentSession.raster(app.alignment, [-app.offset app.offset], app.neuronNo, app.PlotsPanel)
            end
        end

        % Value changed function: AlignmentDropDown
        function AlignmentDropDownValueChanged(app, event)
            value = app.AlignmentDropDown.Value;
            if ~strcmp(string(value), "Select an alignment")
                app.alignment = value;
            end
        end

        % Button pushed function: PSTHButton
        function PSTHButtonPushed(app, event)
            if app.neuronNo <= numel(app.currentSession.spikes) && ismember(strcat('x_', app.alignment), fields(app.currentSession.timestamps.keys))
                delete(app.PlotsPanel.Children)
                app.currentSession.psth(app.alignment, [-app.offset app.offset], app.neuronNo, app.PlotsPanel)
            end
        end

        % Value changed function: OffsetSlider
        function OffsetSliderValueChanged(app, event)
            app.offset = app.OffsetSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.RasterButton = uibutton(app.UIFigure, 'push');
            app.RasterButton.ButtonPushedFcn = createCallbackFcn(app, @RasterButtonPushed, true);
            app.RasterButton.Text = 'Raster';
            app.NeuronDropDownLabel = uilabel(app.UIFigure);
            app.NeuronDropDownLabel.Text = 'Neuron';
            app.NeuronDropDown = uidropdown(app.UIFigure);
            app.NeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuronDropDownValueChanged, true);
            app.OffsetsecondsSliderLabel = uilabel(app.UIFigure);
            app.OffsetsecondsSliderLabel.Text = 'Offset (seconds)';
            app.OffsetSlider = uislider(app.UIFigure);
            app.OffsetSlider.Limits = [0 5];
            app.OffsetSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetSliderValueChanged, true);
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.DigraphPanel = uipanel(app.UIFigure);
            app.DigraphPanel.Title = 'Digraph';
            app.AlignmentDropDownLabel = uilabel(app.UIFigure);
            app.AlignmentDropDownLabel.Text = 'Alignment';
            app.AlignmentDropDown = uidropdown(app.UIFigure);
            app.AlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmentDropDownValueChanged, true);
            app.PSTHButton = uibutton(app.UIFigure, 'push');
            app.PSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHButtonPushed, true);
            app.PSTHButton.Text = 'PSTH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbbasCentral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end